classdef FourierSeriesVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        FourierSeriesVizualizerLabel  matlab.ui.control.Label
        SignalTypeListBoxLabel        matlab.ui.control.Label
        SignalTypeListBox             matlab.ui.control.ListBox
        TimeKnobLabel                 matlab.ui.control.Label
        TimeKnob                      matlab.ui.control.Knob
        NumberofwavesKnobLabel        matlab.ui.control.Label
        NumberofwavesKnob             matlab.ui.control.Knob
    end


    properties (Access = private)
        N; % Number of waves
        t;
        ref;
        y;
    end

    methods (Access = private)
        function f = square_wave_fourier(app)
            f = zeros(size(app.t));
            for id = 1:app.N
                if(mod(id, 2)==1)
                    f = f + (1/id)*sin(id*app.t);
                end
            end
            f = f*(4/pi);
        end
        
        function f = square_wave_ref(app)
            f = zeros(size(app.t));
            th = pi;
            for id = 1:length(app.t)
                if(app.t(id) < th)
                    f(id) = 1;
                elseif(app.t(id) < (th+pi))
                    f(id) = -1;
                else
                    th = th + 2*pi;
                    f(id) = 1;
                end
            end
        end
        
        function fourier_plot(app)
            app.ref = square_wave_ref(app);
            app.y = square_wave_fourier(app);
            plot(app.UIAxes, app.t, app.ref, 'b-', app.t, app.y, 'r-');
            legend(app.UIAxes, 'Reference', 'Signal');
            ylim(app.UIAxes, [-1.5 1.5]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.N = app.NumberofwavesKnob.Value;
            app.t = [0:0.01:app.TimeKnob.Value];
            fourier_plot(app);
        end

        % Value changed function: TimeKnob
        function TimeKnobValueChanged(app, event)
            value = app.TimeKnob.Value;
            app.t = [0:0.01:value];
            fourier_plot(app);
        end

        % Value changed function: NumberofwavesKnob
        function NumberofwavesKnobValueChanged(app, event)
            value = app.NumberofwavesKnob.Value;
            app.N = value;
            fourier_plot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal');
            xlabel(app.UIAxes, 'Time [s]');
            app.FourierSeriesVizualizerLabel = uilabel(app.UIFigure);
            app.FourierSeriesVizualizerLabel.Text = 'Fourier Series Vizualizer';
            app.SignalTypeListBoxLabel = uilabel(app.UIFigure);
            app.SignalTypeListBoxLabel.Text = 'Signal Type';
            app.SignalTypeListBox = uilistbox(app.UIFigure);
            app.SignalTypeListBox.Items = {'Square', 'Triangle', 'Saw-Tooth'};
            app.SignalTypeListBox.Value = 'Square';
            app.TimeKnobLabel = uilabel(app.UIFigure);
            app.TimeKnobLabel.Text = 'Time';
            app.TimeKnob = uiknob(app.UIFigure, 'continuous');
            app.TimeKnob.Limits = [10 60];
            app.TimeKnob.ValueChangedFcn = createCallbackFcn(app, @TimeKnobValueChanged, true);
            app.TimeKnob.Value = 10;
            app.NumberofwavesKnobLabel = uilabel(app.UIFigure);
            app.NumberofwavesKnobLabel.Text = 'Number of waves';
            app.NumberofwavesKnob = uiknob(app.UIFigure, 'continuous');
            app.NumberofwavesKnob.ValueChangedFcn = createCallbackFcn(app, @NumberofwavesKnobValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = FourierSeriesVisualizer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end